classdef SpeakerSim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % UI Figure
        LabelNumericEditField  matlab.ui.control.Label            % DC_R
        appRe                  matlab.ui.control.NumericEditField % [0 Inf]
        LabelNumericEditField6 matlab.ui.control.Label            % f(Hz)_min
        f_min                  matlab.ui.control.NumericEditField % [0 Inf]
        Label                  matlab.ui.control.Label            % f(Hz)_max
        f_max                  matlab.ui.control.NumericEditField % [0 Inf]
        LabelSlider            matlab.ui.control.Label            % Intervals
        Intervals              matlab.ui.control.Slider           % [10 1000]
        UIAxes                 matlab.ui.control.UIAxes           % Simplif...
        LabelNumericEditField7 matlab.ui.control.Label            % Z max
        zmax                   matlab.ui.control.NumericEditField % [0 Inf]
        Button                 matlab.ui.control.Button           % Save Fig
        LabelSlider2           matlab.ui.control.Label            % Le
        LeSlider               matlab.ui.control.Slider           % [0 7.00...
        Label2                 matlab.ui.control.Label            % Rp
        RpSlider               matlab.ui.control.Slider           % [0 100]
        Label3                 matlab.ui.control.Label            % Cp
        CpSlider               matlab.ui.control.Slider           % [0 3.50...
        Label4                 matlab.ui.control.Label            % Lp
        LpSlider               matlab.ui.control.Slider           % [0 5.00...
        LeDisp                 matlab.ui.control.NumericEditField % [-Inf Inf]
        RpDisp                 matlab.ui.control.NumericEditField % [-Inf Inf]
        CpDisp                 matlab.ui.control.NumericEditField % [-Inf Inf]
        LpDisp                 matlab.ui.control.NumericEditField % [-Inf Inf]
        FigLoad                matlab.ui.control.Button           % Referen...
        updateBtn              matlab.ui.control.Button           % Update ...
    end


    properties (Access = private)
        x_ref % Description
        y_ref % Description
        Re % Description
        Le % Description
        Rp % Description
        Cp % Description
        Lp % Description
        changed % Hit the update graph button to change this, which activates the update.
        changedSto % Description
    end

    methods (Access = private)
        
        function update(app)
            
            if app.LeSlider.Value ~= app.Le || app.Rp ~= app.RpSlider.Value || app.Cp ~= app.CpSlider.Value || app.Lp ~= app.LpSlider.Value || app.changedSto ~= app.changed
                f = logspace(log10(app.f_min.Value), log10(app.f_max.Value), app.Intervals.Value);
                app.Re = app.appRe.Value;
                app.Le = app.LeSlider.Value;
                app.Rp = app.RpSlider.Value;
                app.Cp = app.CpSlider.Value;
                app.Lp = app.LpSlider.Value;
            
                Z = app.Re + 1i.*app.Le.*f.*2.*pi + (1./( (1/app.Rp)+(1i.*f.*2.*pi.*app.Cp)+(1./(1i.*f.*2.*pi.*app.Lp)) ));
                Z_amp = abs(Z);
            
                app.UIAxes.XLim = [app.f_min.Value app.f_max.Value];
                app.UIAxes.YLim = [0 app.zmax.Value];
                semilogx(app.UIAxes, f ,Z_amp, app.x_ref, app.y_ref);
            
                app.LeDisp.Value = app.LeSlider.Value;
                app.RpDisp.Value = app.RpSlider.Value;
                app.CpDisp.Value = app.CpSlider.Value;
                app.LpDisp.Value = app.LpSlider.Value;
                
                app.changedSto = app.changed;
            end
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.x_ref = 0;
            app.y_ref = 0;
            app.Re = app.appRe.Value;
            app.Le = app.LeSlider.Value;
            app.Rp = app.RpSlider.Value;
            app.Cp = app.CpSlider.Value;
            app.Lp = app.LpSlider.Value;
            app.changed = 0;
            app.changedSto = 0;
            n = 1;
            while n==1
                update(app);
                pause(0.15);
            end
        end

        % Button button pushed function
        function SaveFig(app)
            f = logspace(log10(app.f_min.Value), log10(app.f_max.Value), app.Intervals.Value);
            app.Re = app.appRe.Value;
            app.Le = app.LeSlider.Value;
            app.Rp = app.RpSlider.Value;
            app.Cp = app.CpSlider.Value;
            app.Lp = app.LpSlider.Value;
            
            Z = app.Re + 1i.*app.Le.*f.*2.*pi + (1./( (1/app.Rp)+(1i.*f.*2.*pi.*app.Cp)+(1./(1i.*f.*2.*pi.*app.Lp)) ));
            Z_amp = abs(Z);
            
            
            h1 = figure(1);
            ax1 = axes('Parent', h1);
            semilogx(ax1,f,Z_amp,app.x_ref,app.y_ref);
            ylabel(ax1, 'Impedance amplitude (ohm)');
            xlabel(ax1, 'frequency (Hz)');
            grid(ax1,'on');
            xlim(ax1,[app.f_min.Value app.f_max.Value]);
            ylim(ax1, [0 app.zmax.Value]);
            title(ax1, ['Simplified speaker model']);
        end

        % FigLoad button pushed function
        function LoadFig(app)
            uiopen('load');
            h = gcf;
            axesObjs = get(h, 'Children');
            dataObjs = get(axesObjs, 'Children');
            
            
            app.x_ref = get(dataObjs, 'XData');
            app.y_ref = get(dataObjs, 'YData');
            
            close
        end

        % updateBtn button pushed function
        function updateGraph(app)
            app.changed = app.changed + 1;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'DC_R';
            app.appRe = uieditfield(app.UIFigure, 'numeric');
            app.appRe.Limits = [0 Inf];
            app.appRe.Value = 4;
            app.LabelNumericEditField6 = uilabel(app.UIFigure);
            app.LabelNumericEditField6.Text = 'f(Hz)_min';
            app.f_min = uieditfield(app.UIFigure, 'numeric');
            app.f_min.Limits = [0 Inf];
            app.f_min.Value = 20;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'f(Hz)_max';
            app.f_max = uieditfield(app.UIFigure, 'numeric');
            app.f_max.Limits = [0 Inf];
            app.f_max.ValueDisplayFormat = '%11.6g';
            app.f_max.Value = 24000;
            app.LabelSlider = uilabel(app.UIFigure);
            app.LabelSlider.Text = 'Intervals';
            app.Intervals = uislider(app.UIFigure);
            app.Intervals.Limits = [10 1000];
            app.Intervals.Value = 100;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Simplified model speaker');
            xlabel(app.UIAxes, 'frequency (Hz)');
            ylabel(app.UIAxes, 'Impedance (Ohm)');
            app.LabelNumericEditField7 = uilabel(app.UIFigure);
            app.LabelNumericEditField7.Text = 'Z max';
            app.zmax = uieditfield(app.UIFigure, 'numeric');
            app.zmax.Limits = [0 Inf];
            app.zmax.Value = 70;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @SaveFig);
            app.Button.Text = 'Save Fig';
            app.LabelSlider2 = uilabel(app.UIFigure);
            app.LabelSlider2.Text = 'Le';
            app.LeSlider = uislider(app.UIFigure);
            app.LeSlider.Limits = [0 0.007];
            app.LeSlider.Value = 0.004;
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Rp';
            app.RpSlider = uislider(app.UIFigure);
            app.RpSlider.Value = 30;
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Cp';
            app.CpSlider = uislider(app.UIFigure);
            app.CpSlider.Limits = [0 0.0035];
            app.CpSlider.Value = 0.001;
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = 'Lp';
            app.LpSlider = uislider(app.UIFigure);
            app.LpSlider.Limits = [0 0.05];
            app.LpSlider.Value = 0.02;
            app.LeDisp = uieditfield(app.UIFigure, 'numeric');
            app.LeDisp.Editable = 'off';
            app.RpDisp = uieditfield(app.UIFigure, 'numeric');
            app.RpDisp.Editable = 'off';
            app.CpDisp = uieditfield(app.UIFigure, 'numeric');
            app.CpDisp.Editable = 'off';
            app.LpDisp = uieditfield(app.UIFigure, 'numeric');
            app.LpDisp.Editable = 'off';
            app.FigLoad = uibutton(app.UIFigure, 'push');
            app.FigLoad.ButtonPushedFcn = createCallbackFcn(app, @LoadFig);
            app.FigLoad.Text = 'Reference Figure';
            app.updateBtn = uibutton(app.UIFigure, 'push');
            app.updateBtn.ButtonPushedFcn = createCallbackFcn(app, @updateGraph);
            app.updateBtn.Text = 'Update Graph';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SpeakerSim()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end