classdef ZobelSim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure                   % Zobel ...
        UIAxes                  matlab.ui.control.UIAxes           % Zobel ...
        LabelNumericEditField7  matlab.ui.control.Label            % Z max
        zmax                    matlab.ui.control.NumericEditField % [0 Inf]
        Button                  matlab.ui.control.Button           % Save Fig
        FigLoad                 matlab.ui.control.Button           % Refere...
        updateBtn               matlab.ui.control.Button           % Update...
        LabelSlider             matlab.ui.control.Label            % Cz
        SliderCz                matlab.ui.control.Slider           % [0 2.0...
        Label                   matlab.ui.control.Label            % Rz
        SliderRz                matlab.ui.control.Slider           % [0 10]
        LabelNumericEditField   matlab.ui.control.Label            % Cz
        Cz                      matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField8  matlab.ui.control.Label            % Cz_min
        Cz_min                  matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField9  matlab.ui.control.Label            % Cz_max
        Cz_max                  matlab.ui.control.NumericEditField % [-Inf ...
        Label2                  matlab.ui.control.Label            % Rz
        Rz                      matlab.ui.control.NumericEditField % [0 Inf]
        Label3                  matlab.ui.control.Label            % Rz_min
        Rz_min                  matlab.ui.control.NumericEditField % [0 Inf]
        Label4                  matlab.ui.control.Label            % Rz_max
        Rz_max                  matlab.ui.control.NumericEditField % [0 Inf]
        LabelNumericEditField10 matlab.ui.control.Label            % Update...
        UpdateCount             matlab.ui.control.NumericEditField % [-Inf ...
    end


    properties (Access = private)
        x_ref % Description
        y_ref % Description
        changed % Hit the update graph button to change this, which activates the update.
        changedSto % Description
        n % Description
    end

    methods (Access = private)
        
        function update(app)
            
            if app.changedSto ~= app.changed
                f = app.x_ref;
            
                Z = 1./((1./app.y_ref) + (1i.*2.*pi.*f.*app.SliderCz.Value) + (1./app.SliderRz.Value));
                Z_amp = abs(Z);
            
                app.UIAxes.XLim = [20 24000];
                app.UIAxes.YLim = [0 app.zmax.Value];
                semilogx(app.UIAxes, f ,Z_amp, app.x_ref, app.y_ref);
          
                app.changedSto = app.changed;
                
                app.Cz.Value = app.SliderCz.Value;
                app.Rz.Value = app.SliderRz.Value;
                
                app.UpdateCount.Value = app.changed;
            end
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.x_ref = 0;
            app.y_ref = 0;
            app.changed = 0;
            app.changedSto = 0;
            app.n = 1;
            while exist('app')==1 %Loop that executes while app is running.
                a=app.n;
                if a==1 %Check if app must be closed or updated.
                    update(app);
                    pause(0.15);
                else
                    delete(app);
                    return
                end
            end
        end

        % Button button pushed function
        function SaveFig(app)
            f = app.x_ref;
            
            Z = 1./((1./app.y_ref) + (1i.*2.*pi.*f.*app.SliderCz.Value) + (1./app.SliderRz.Value));
            Z_amp = abs(Z);
            
            h1 = figure(1);
            ax1 = axes('Parent', h1);
            semilogx(ax1,f,Z_amp,app.x_ref,app.y_ref);
            ylabel(ax1, 'Impedance amplitude (ohm)');
            xlabel(ax1, 'frequency (Hz)');
            grid(ax1,'on');
            xlim(ax1,[20 24000]);
            ylim(ax1, [0 app.zmax.Value]);
            title(ax1, ['Simplified speaker model with Zobel network']);
        end

        % FigLoad button pushed function
        function LoadFig(app)
            uiopen('load');
            h = gcf;
            axesObjs = get(h, 'Children');
            dataObjs = get(axesObjs, 'Children');
            
            x_ref_full = get(dataObjs, 'XData');
            y_ref_full = get(dataObjs, 'YData');
            
            app.x_ref = x_ref_full(1:131072);
            app.y_ref = y_ref_full(1:131072);
            
            app.changed = app.changed + 1;
            
            close
        end

        % updateBtn button pushed function
        function updateGraph(app)
            app.changed = app.changed + 1;
        end

        % SliderCz value changed function
        function CzChanged(app)
            app.changed = app.changed + 1;
        end

        % SliderRz value changed function
        function RzChanged(app)
            app.changed = app.changed + 1;
        end

        % Cz value changed function
        function FieldCzChanged(app)
            set(app.SliderCz,'Value',app.Cz.Value);
            app.changed = app.changed + 1;
        end

        % Rz value changed function
        function FieldRzChanged(app)
            set(app.SliderRz,'Value',app.Rz.Value);
            app.changed = app.changed + 1;
        end

        % Cz_min value changed function
        function Cz_minChange(app)
            set(app.SliderCz,'Limits',[app.Cz_min.Value app.Cz_max.Value]);
            app.changed = app.changed + 1;
        end

        % Cz_max value changed function
        function Cz_maxChanged(app)
            set(app.SliderCz,'Limits',[app.Cz_min.Value app.Cz_max.Value]);
            app.changed = app.changed + 1;
        end

        % Rz_min value changed function
        function Rz_minChanged(app)
            set(app.SliderRz,'Limits',[app.Rz_min.Value app.Rz_max.Value]);
            app.changed = app.changed + 1;
        end

        % Rz_max value changed function
        function Rz_maxChanged(app)
            set(app.SliderRz,'Limits',[app.Rz_min.Value app.Rz_max.Value]);
            app.changed = app.changed + 1;
        end

        % UIFigure close request function
        function UIFigureCloseRequest(app)
            app.n = 0;    %If n != 1, the app will close.
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Zobel Simulator 2000';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest);
            setAutoResize(app, app.UIFigure, true)
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Zobel Network Simulation');
            xlabel(app.UIAxes, 'frequency (Hz)');
            ylabel(app.UIAxes, 'Impedance (Ohm)');
            app.LabelNumericEditField7 = uilabel(app.UIFigure);
            app.LabelNumericEditField7.Text = 'Z max';
            app.zmax = uieditfield(app.UIFigure, 'numeric');
            app.zmax.Limits = [0 Inf];
            app.zmax.Value = 40;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @SaveFig);
            app.Button.Text = 'Save Fig';
            app.FigLoad = uibutton(app.UIFigure, 'push');
            app.FigLoad.ButtonPushedFcn = createCallbackFcn(app, @LoadFig);
            app.FigLoad.Text = 'Reference Figure';
            app.updateBtn = uibutton(app.UIFigure, 'push');
            app.updateBtn.ButtonPushedFcn = createCallbackFcn(app, @updateGraph);
            app.updateBtn.Text = 'Update Graph';
            app.LabelSlider = uilabel(app.UIFigure);
            app.LabelSlider.Text = 'Cz';
            app.SliderCz = uislider(app.UIFigure);
            app.SliderCz.Limits = [0 0.0002];
            app.SliderCz.ValueChangedFcn = createCallbackFcn(app, @CzChanged);
            app.SliderCz.Value = 5e-05;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Rz';
            app.SliderRz = uislider(app.UIFigure);
            app.SliderRz.Limits = [0 10];
            app.SliderRz.ValueChangedFcn = createCallbackFcn(app, @RzChanged);
            app.SliderRz.Value = 6.8;
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'Cz';
            app.Cz = uieditfield(app.UIFigure, 'numeric');
            app.Cz.ValueChangedFcn = createCallbackFcn(app, @FieldCzChanged);
            app.LabelNumericEditField8 = uilabel(app.UIFigure);
            app.LabelNumericEditField8.Text = 'Cz_min';
            app.Cz_min = uieditfield(app.UIFigure, 'numeric');
            app.Cz_min.ValueChangedFcn = createCallbackFcn(app, @Cz_minChange);
            app.LabelNumericEditField9 = uilabel(app.UIFigure);
            app.LabelNumericEditField9.Text = 'Cz_max';
            app.Cz_max = uieditfield(app.UIFigure, 'numeric');
            app.Cz_max.ValueChangedFcn = createCallbackFcn(app, @Cz_maxChanged);
            app.Cz_max.Value = 0.0002;
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Rz';
            app.Rz = uieditfield(app.UIFigure, 'numeric');
            app.Rz.ValueChangedFcn = createCallbackFcn(app, @FieldRzChanged);
            app.Rz.Limits = [0 Inf];
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Rz_min';
            app.Rz_min = uieditfield(app.UIFigure, 'numeric');
            app.Rz_min.ValueChangedFcn = createCallbackFcn(app, @Rz_minChanged);
            app.Rz_min.Limits = [0 Inf];
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = 'Rz_max';
            app.Rz_max = uieditfield(app.UIFigure, 'numeric');
            app.Rz_max.ValueChangedFcn = createCallbackFcn(app, @Rz_maxChanged);
            app.Rz_max.Limits = [0 Inf];
            app.Rz_max.Value = 10;
            app.LabelNumericEditField10 = uilabel(app.UIFigure);
            app.LabelNumericEditField10.Text = 'UpdateCount';
            app.UpdateCount = uieditfield(app.UIFigure, 'numeric');
            app.UpdateCount.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ZobelSim()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end